classdef img_recognition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        naiveImageRecognition  matlab.ui.Figure
        Image                  matlab.ui.control.Image
        RecognizeButton        matlab.ui.control.Button
        UploadButton           matlab.ui.control.Button
        Label                  matlab.ui.control.Label
    end

    
    properties (Access = private)
        categoryClassifier = train_category();
        img;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            [file,path] = uigetfile('*.jpg');
            app.img = imread(fullfile(path,file));
            app.Image.ImageSource= fullfile(path,file);
            app.Label.Visible = 'off';
        end

        % Button pushed function: RecognizeButton
        function RecognizeButtonPushed(app, event)
            [labelIdx, scores] = predict(app.categoryClassifier, app.img);
            app.Label.Text = app.categoryClassifier.Labels(labelIdx);
            app.Label.Visible = "on";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.naiveImageRecognition = uifigure('Visible', 'off');
            app.naiveImageRecognition.Name = 'Image Recognition';
            app.Image = uiimage(app.naiveImageRecognition);
            app.RecognizeButton = uibutton(app.naiveImageRecognition, 'push');
            app.RecognizeButton.ButtonPushedFcn = createCallbackFcn(app, @RecognizeButtonPushed, true);
            app.RecognizeButton.Text = 'Recognize';
            app.UploadButton = uibutton(app.naiveImageRecognition, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.Label = uilabel(app.naiveImageRecognition);
            app.Label.Visible = 'off';
            % Show the figure after all components are created
            app.naiveImageRecognition.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = img_recognition

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.naiveImageRecognition)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.naiveImageRecognition)
        end
    end
end